classdef integrated_black_linux < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Lidar                 matlab.ui.control.UIAxes
        MillimeterWaveRadar1  matlab.ui.control.UIAxes
        MillimeterWaveRadar2  matlab.ui.control.UIAxes
        MillimeterWaveRadar3  matlab.ui.control.UIAxes
        MillimeterWaveRadar4  matlab.ui.control.UIAxes
        RGBPic                matlab.ui.control.UIAxes
        DeepPic               matlab.ui.control.UIAxes
        Label                 matlab.ui.control.Label
        TextOut               matlab.ui.control.TextArea
        openDir               matlab.ui.control.Button
        begin                 matlab.ui.control.Button
        Pause                 matlab.ui.control.Button
        Stop                  matlab.ui.control.Button
        GPSRoute              matlab.ui.control.UIAxes
        Euler                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dirRGB % Description
        dirDeepth % Description
        MiliRadarData1
        MiliRadarData2
        MiliRadarData3
        MiliRadarData4
        imuData
        PauseFlag = false
        StopFlag = false
        cellText = {}
        textIndex = 1
        TimeNum
        timeLidarOpen
        LidarData % Description
        liadrDataRemain %解析后的激光雷达csv数量numCSV
        indexLidarCSV = 1 % 当前激光雷达csv序号
        lidarDirCSV % Description
        gpsData % Description
    end
    
    methods (Access = private)
        %************************自定义函数************************
        function rotateCube(app,yaw,pitch,roll)
            %调整正方体角度
            app.Euler.View=[-37.5,30]-[yaw,pitch];
%             CameraUpVector = 
%             direction = [1 0 0];
%             rotate(app.Euler.Children,direction,roll)
%             direction = [0 1 0];
%             rotate(app.Euler.Children,direction,pitch)
%             direction = [0 0 1];
%             rotate(app.Euler.Children,direction,yaw)
        end
        function plotCuber(app)
            %画正方体
            SideLengthize=2;
            origin=[0,0,0];         
            x=([0 1 1 0 0 0;1 1 0 0 1 1;1 1 0 0 1 1;0 1 1 0 0 0]-0.5)*SideLengthize+origin(1);
            y=([0 0 1 1 0 0;0 1 1 0 0 0;0 1 1 0 1 1;0 0 1 1 1 1]-0.5)*SideLengthize+origin(2);
            z=([0 0 0 0 0 1;0 0 0 0 0 1;1 1 1 1 0 1;1 1 1 1 0 1]-0.5)*SideLengthize+origin(3);
            for i=1:6
                h=patch(app.Euler,x(:,i),y(:,i),z(:,i),'g');
                set(h,'edgecolor','k','facealpha',0.5)
            end
            axis(app.Euler,sqrt(3)*[-1 1 -1 1 -1 1])
        end   
        function plotTansformCuber(app,yaw,pitch,roll)
            %画正方体
            SideLengthize=2;
            origin=[0,0,0];
            x=([0 1 1 0 0 0;1 1 0 0 1 1;1 1 0 0 1 1;0 1 1 0 0 0]-0.5)*SideLengthize+origin(1);
            y=([0 0 1 1 0 0;0 1 1 0 0 0;0 1 1 0 1 1;0 0 1 1 1 1]-0.5)*SideLengthize+origin(2);
            z=([0 0 0 0 0 1;0 0 0 0 0 1;1 1 1 1 0 1;1 1 1 1 0 1]-0.5)*SideLengthize+origin(3);
            %定义旋转顺序321，也就是yaw,pitch,roll
            trans_3=[cosd(yaw),-sind(yaw),0;
                sind(yaw),cosd(yaw),0;
                0,0,1];
            trans_2=[cosd(pitch),0,sind(pitch);
                0,1,0;
                -sind(pitch),0,cosd(pitch)];
            trans_1=[1,0,0;
                0,cosd(roll),-sind(roll);
                0,sind(roll),cosd(roll)];
            for ii=1:6
                side_trans=zeros(3,4);
                for jj=1:4
                    side_trans(:,jj)=trans_1*trans_2*trans_3*[x(jj,ii);y(jj,ii);z(jj,ii)];
                end
                set(app.Euler.Children(ii),'Vertices',[side_trans(1,:)',side_trans(2,:)',side_trans(3,:)'])
                %h=patch(app.Euler,side_trans(1,:)',side_trans(2,:)',side_trans(3,:)','g');
                set(app.Euler.Children(ii),'edgecolor','k','facealpha',0.5)
            end
            axis(app.Euler,sqrt(3)*[-1 1 -1 1 -1 1])
        end
    
        function plotLidar(app,index_lidar)
            %Lidar输出
            if index_lidar==1
                cut=1:app.LidarData.sequence(1)-1;
            else
                cut=app.LidarData.sequence(index_lidar-1):app.LidarData.sequence(index_lidar)-1;
            end
            scatter3(app.Lidar,app.LidarData.x(cut),app.LidarData.y(cut),app.LidarData.z(cut),1,app.LidarData.z(cut),'filled');
            app.Lidar.View=[-86.768231720139100,10.053691808182107];
            %app.Lidar.View=[-76.438711424936140,25.727985229411090];
            %app.Lidar.View=[0,90];
            pause(1.e-5)
        end 
        function [AngleHorizontal,AngleVertical,dist,lidarTimeNum,reflectivity]=readLidarCSV(app,filename)
            %LidarCSV读取
            delimiter = ',';
            formatSpec = '%f%f%f%f%f%[^\n\r]';
            fileID = fopen(filename,'r');
            dataArray = textscan(fileID, formatSpec, 'Delimiter', delimiter, 'TextType', 'string', 'EmptyValue', NaN,  'ReturnOnError', false);
            fclose(fileID);
            AngleHorizontal = dataArray{:, 1};
            AngleVertical = dataArray{:, 2};
            dist = dataArray{:, 3}/100;%单位米
            reflectivity = dataArray{:, 4};
            lidarTimeNum = dataArray{:, 5}/1000;%单位秒
            clearvars filename delimiter formatSpec fileID dataArray ans;
%             del=reflectivity==0;
%             AngleVertical(del)=[];
%             AngleHorizontal(del)=[];
%             dist(del)=[];
%             lidarTimeNum(del)=[];
%             %reflectivity(del)=[];
        end
        function plotRealSense(app,index_RealSense)
            %RealSense输出
            nameRGB=[app.dirRGB(index_RealSense).folder,'/',app.dirRGB(index_RealSense).name];
            nameDeepth=[app.dirDeepth(index_RealSense).folder,'/',app.dirDeepth(index_RealSense).name];
            ImRGB=imresize(imread(nameRGB),185/720);
            ImDeepth=imresize(imread(nameDeepth),185/720);
            image(app.RGBPic,ImRGB);set(app.RGBPic,'Xtick',[],'Ytick',[]);
            image(app.DeepPic,ImDeepth);set(app.DeepPic,'Xtick',[],'Ytick',[]);
            pause(1.e-5)
        end 
        function plotMiliRadar(app,index_miliRadar)
            %毫米波雷达1
            datacut=app.MiliRadarData1{index_miliRadar};
            if datacut==0
                cla(app.MillimeterWaveRadar1);
            else
                %color=zeros(size(datacut,1),1);
                %for jj=1:size(datacut,1)
                    %color(jj)=norm(datacut(jj,1:3));
                %end
                scatter3(app.MillimeterWaveRadar1,datacut(:,1),datacut(:,2),datacut(:,3),4,datacut(:,3),'filled');
                pause(1.e-5)
            end
            
            %毫米波雷达2
            datacut=app.MiliRadarData2{index_miliRadar};
            if datacut==0
                cla(app.MillimeterWaveRadar2);
            else
                scatter3(app.MillimeterWaveRadar2,datacut(:,1),datacut(:,2),datacut(:,3),4,datacut(:,3),'filled');
                pause(1.e-5)
            end
            
            %毫米波雷达3
            datacut=app.MiliRadarData3{index_miliRadar};
            if datacut==0
                cla(app.MillimeterWaveRadar2);
            else
                scatter3(app.MillimeterWaveRadar3,datacut(:,1),datacut(:,2),datacut(:,3),4,datacut(:,3),'filled');
                pause(1.e-5)
            end
            
            %毫米波雷达4
            datacut=app.MiliRadarData4{index_miliRadar};
            if datacut==0
                cla(app.MillimeterWaveRadar2);
            else
                %x,y数据反向
                scatter3(app.MillimeterWaveRadar4,datacut(:,2),datacut(:,1),datacut(:,3),4,datacut(:,3),'filled');
                pause(1.e-5)
            end
            
        end
        function printText(app,string)
            app.cellText{app.textIndex} = sprintf('%s\n', string);
            app.TextOut.Value = app.cellText;
            app.textIndex = app.textIndex+1;
            pause(1.e-5)
        end
        function printText2(app,string)
            app.TextOut.Value = sprintf('%s\n', string);
            pause(1.e-5)
        end
        function scatterCoor = readData1843(app,filename)
            fid1 = fopen(filename,'r');
            currFrame = {};
            tline = fgetl(fid1);
            while ischar(tline)
                currFrame = [currFrame,tline];
                tline = fgetl(fid1);
            end
            fclose(fid1);
            
            timenow = datestr(now);
            timenow(timenow=='-'|timenow==' '|timenow==':')='_';
            fid2 = fopen(['data_',timenow,'.txt'],'a');
            for n = 1:size(currFrame,2)
                fprintf(fid2,currFrame{1,n});
            end
            fclose(fid2);
            
            fid = fopen(['data_',timenow,'.txt'],'r');
            C = textscan(fid,'%s');
            C = C{1};
            C = C{1,1};
            fclose(fid);
            delete(['data_',timenow,'.txt']);
            
            data = zeros(1,length(C));
            for indx = 0:9
                data(C==num2str(indx))=indx;
            end
            data(C=='a') = 10;
            data(C=='b') = 11;
            data(C=='c') = 12;
            data(C=='d') = 13;
            data(C=='e') = 14;
            data(C=='f') = 15;
            
            data = reshape(data,2,[]);
            bytevec = data(1,:)*16+data(2,:);
            bytevecStr = char(bytevec);
            bytevec = uint8(bytevec);
            startIdx = strfind(bytevecStr, char([2 1 4 3 6 5 8 7]));
            numFrames = length(startIdx);
            
            scatterCoor = {};
            for n = 1:numFrames-1
                startIndex = startIdx(n);
                if startIdx(n+1)-startIdx(n)>80
                    totalPacketLen = typecast(bytevec...
                        (startIndex+12:startIndex+15),'uint32');
                    if startIndex+totalPacketLen==startIdx(n+1)
                        currFrame = bytevec(startIndex:startIndex+totalPacketLen-1);
                        numDetectedObj = typecast(currFrame(29:32),'uint32');
                        if numDetectedObj>0
                            TLVLen = typecast(currFrame(45:48),'uint32');
                            detectedObjs = currFrame(49:48+TLVLen);
                            detectedObjs = typecast(detectedObjs,'single');
                            detectedObjs = reshape(detectedObjs,4,[]);
                            detectedObjs = detectedObjs';
                            TLVLen_sideInfo = typecast(...
                                currFrame(48+TLVLen+5:48+TLVLen+8),'uint32');
                            SideInfo = currFrame(48+TLVLen+9:48+TLVLen+8+TLVLen_sideInfo);
                            SideInfo = typecast(SideInfo,'int16');
                            SideInfo = reshape(SideInfo,2,[]);
                            SideInfo = single(SideInfo');
                            scatter_coor = [detectedObjs,SideInfo];
                            scatterCoor = [scatterCoor,scatter_coor];
                        else
                            scatterCoor = [scatterCoor,0];
                        end
                    end
                end
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %控制激光雷达显示范围
            Xlim = [-20*0 40]/1;
            Ylim = [-25 25]/1;
            Zlim = [0 10];
            Clim = [1 100]/10;
            caxis(app.Lidar,Clim);
            app.Lidar.XLim = Xlim;
            app.Lidar.YLim = Ylim;
            app.Lidar.ZLim = Zlim;
            %%控制激光雷达视角
            app.Lidar.View=[-86.768231720139100,10.053691808182107];%-37.5,30%-76.438711424936140,25.727985229411090
            
            %绘制正方体
            app.Euler.Color = 'none';
            plotCuber(app)
            
            %控制毫米波雷达显示范围
            Xlim = [-10 10];
            Ylim = [0 20];
            Zlim = [-1 3];
            Clim = Zlim;
            caxis(app.MillimeterWaveRadar1,Clim);
            app.MillimeterWaveRadar1.XLim = Xlim;
            app.MillimeterWaveRadar1.YLim = Ylim;
            app.MillimeterWaveRadar1.ZLim = Zlim;
            %axis(app.MillimeterWaveRadar1,'equal')
            caxis(app.MillimeterWaveRadar2,Clim);
            app.MillimeterWaveRadar2.XLim = Xlim;
            app.MillimeterWaveRadar2.YLim = Ylim;
            app.MillimeterWaveRadar2.ZLim = Zlim;
            %axis(app.MillimeterWaveRadar2,'equal')
            caxis(app.MillimeterWaveRadar3,Clim);
            app.MillimeterWaveRadar3.XLim = Xlim;
            app.MillimeterWaveRadar3.YLim = Ylim;
            app.MillimeterWaveRadar3.ZLim = Zlim;
            %axis(app.MillimeterWaveRadar3,'equal')
            %x,y互换
            caxis(app.MillimeterWaveRadar4,Clim);
            app.MillimeterWaveRadar4.XLim = Ylim;
            app.MillimeterWaveRadar4.YLim = Xlim;
            app.MillimeterWaveRadar4.ZLim = Zlim;
            %axis(app.MillimeterWaveRadar4,'equal')
            %控制RealSense显示区域
            %图片窗口预处理
            allBlack=uint8(zeros(720,1280,3));%强制黑色
            imshow(allBlack,'Parent',app.RGBPic);
            imshow(allBlack,'Parent',app.DeepPic);
            
            %title颜色修改
            title(app.Lidar, '激光雷达','Color','w')
            title(app.GPSRoute, 'GPS轨迹','Color','w')
            title(app.MillimeterWaveRadar1, '毫米波雷达 右','Color','w')
            title(app.MillimeterWaveRadar2, '毫米波雷达 后','Color','w')
            title(app.MillimeterWaveRadar3, '毫米波雷达 前','Color','w')
            title(app.MillimeterWaveRadar4, '毫米波雷达 左','Color','w')
            title(app.RGBPic, 'RGB','Color','w')
            title(app.DeepPic, 'Deep','Color','w')
            title(app.Euler, '欧拉角显示','Color','w')
        end

        % Button pushed function: openDir
        function openDirButtonPushed(app, event)
            selpath = uigetdir;
            if selpath~=0
                printText(app,'数据加载中...');
                
                %%加载并处理realsense数据
                app.dirRGB = dir([selpath,'/realsense*/rgb/*.jpg']);
                app.dirDeepth = dir([selpath,'/realsense*/depth/*.jpg']);
                
                %读取realsense时间戳
                dirTemp = dir([selpath,'/realsense*']);
                timeRealSense = str2double(strtrim(erase(dirTemp.name,'realsense_')));%realsense启动时间
                dirTemp = dir([selpath,'/',dirTemp.name,'/imu.csv']);
                filename = [dirTemp.folder,'/',dirTemp.name];
                delimiter = ',';
                formatSpec = '%f%*s%*s%*s%*s%*s%*s%[^\n\r]';
                fileID = fopen(filename,'r');
                dataArray = textscan(fileID, formatSpec, 'Delimiter', delimiter, 'TextType', 'string', 'EmptyValue', NaN,  'ReturnOnError', false);
                fclose(fileID);
                realsenseTimeNum = [dataArray{1:end-1}];
                clearvars filename delimiter formatSpec fileID dataArray ans;
                realsenseTimeNum=realsenseTimeNum-realsenseTimeNum(1);%realsense时间戳,单位秒
                printText(app,'realsense数据加载完毕!');
                
                %%加载并处理imu数据
                %读取imu时间戳
                dirTemp = dir([selpath,'/imu*']);
                timeIMU = str2double(strtrim(erase(dirTemp.name,'imu_')));%imu启动时间
                filename = [selpath,'/',dirTemp.name];
                delimiter = ';';
                formatSpec = '%f%f%f%f%*s%f%f%f%[^\n\r]';
                fileID = fopen(filename,'r');
                dataArray = textscan(fileID, formatSpec, 'Delimiter', delimiter, 'TextType', 'string', 'EmptyValue', NaN,  'ReturnOnError', false);
                fclose(fileID);
                app.imuData = table(dataArray{1:end-1}, 'VariableNames', {'yaw','pitch','roll','timenum','lat','lng','mode'});
                clearvars filename delimiter formatSpec fileID dataArray ans;
                app.imuData.lat=app.imuData.lat/1000;
                app.imuData.lng=app.imuData.lng/1000;
                app.imuData.timenum=(app.imuData.timenum-app.imuData.timenum(1))/1.e6;%imu时间戳,单位秒
                printText(app,'imu数据加载完毕!');
                                
                %%加载并处理毫米波雷达
                dirMiliRadar = dir([selpath,'/radar*/*data*.txt']);
                app.MiliRadarData1=readData1843(app,[dirMiliRadar(1).folder,'/',dirMiliRadar(1).name]).';
                app.MiliRadarData2=readData1843(app,[dirMiliRadar(2).folder,'/',dirMiliRadar(2).name]).';
                app.MiliRadarData3=readData1843(app,[dirMiliRadar(3).folder,'/',dirMiliRadar(3).name]).';
                app.MiliRadarData4=readData1843(app,[dirMiliRadar(4).folder,'/',dirMiliRadar(4).name]).';
                
                %读取毫米波雷达时间戳
                dirTemp = dir([selpath,'/radar*']);
                timeMiliRadar = str2double(strtrim(erase(dirTemp.name,'radar_')));%毫米波雷达启动时间
                dirTemp = dir([selpath,'/',dirTemp.name,'/radar1_time*.txt']);
                filename = [dirTemp.folder,'/',dirTemp.name];
                delimiter = ' ';
                formatSpec = '%*s%f%[^\n\r]';
                fileID = fopen(filename,'r');
                dataArray = textscan(fileID, formatSpec, 'Delimiter', delimiter, 'MultipleDelimsAsOne', true, 'TextType', 'string', 'EmptyValue', NaN,  'ReturnOnError', false);
                fclose(fileID);
                miliRadarTimeNum = dataArray{:, 1};
                clearvars filename delimiter formatSpec fileID dataArray ans;
                miliRadarTimeNum=(miliRadarTimeNum-miliRadarTimeNum(1))/1.e3;%毫米波雷达时间戳,单位秒
                printText(app,'毫米波雷达数据加载完毕!');
                
                %%加载并处理激光雷达
                app.lidarDirCSV=dir([selpath,'/*.csv']);
                [~,ind]=sort([app.lidarDirCSV(:).datenum]);
                app.lidarDirCSV=app.lidarDirCSV(ind);
                filename = [app.lidarDirCSV(1).folder,'/',app.lidarDirCSV(1).name];               
                [app.LidarData.AngleHorizontal,AngleVertical,app.LidarData.dist,lidarTimeNum,app.LidarData.reflectivity]=readLidarCSV(app,filename);
                lidarTimeFirst=lidarTimeNum(1);
                lidarTimeNum=lidarTimeNum-lidarTimeNum(1);
                printText(app,'激光雷达数据加载完毕!');                
                %激光雷达时间戳
                dirTemp = dir([selpath,'/Laser*']);
                timeLidar = str2double(strtrim(erase(dirTemp.name,'Laser_')));%激光雷达时间戳,单位秒
                
                %时间戳初始对准
                [timeMax,~]=max([timeMiliRadar,timeRealSense,timeIMU,timeLidar]);
                [miliRadarMin,miliRadarIndexStart]=min(abs(miliRadarTimeNum-(timeMax-timeMiliRadar)));
                [realsenseMin,realsenseIndexStart]=min(abs(realsenseTimeNum-(timeMax-timeRealSense)));
                [imuIndexStartMin,imuIndexStart]=min(abs(app.imuData.timenum-(timeMax-timeIMU)));
                [lidarIndexStartMin,lidarIndexStart]=min(abs(lidarTimeNum-(timeMax-timeLidar)));

                %时间对准,由于table存在应放在数据对准前
                %存在小幅差异
                miliRadarTimeNum=miliRadarTimeNum-miliRadarTimeNum(miliRadarIndexStart);
                miliRadarTimeNum(1:miliRadarIndexStart-1,:)=[];
                realsenseTimeNum=realsenseTimeNum-realsenseTimeNum(realsenseIndexStart);
                realsenseTimeNum(1:realsenseIndexStart-1,:)=[];
                app.imuData.timenum=app.imuData.timenum-app.imuData.timenum(imuIndexStart);
                if app.indexLidarCSV==1
                    %注意全局性,需要标记
                    app.timeLidarOpen.lidarTime=lidarTimeNum(lidarIndexStart)+lidarTimeFirst;
                    app.timeLidarOpen.actualTime=lidarTimeNum(lidarIndexStart);
                end
                lidarTimeNum=lidarTimeNum-lidarTimeNum(lidarIndexStart);
                lidarTimeNum(1:lidarIndexStart-1,:)=[];


                %数据对准
                app.MiliRadarData1(1:miliRadarIndexStart-1,:)=[];
                app.MiliRadarData2(1:miliRadarIndexStart-1,:)=[];
                app.MiliRadarData3(1:miliRadarIndexStart-1,:)=[];
                app.MiliRadarData4(1:miliRadarIndexStart-1,:)=[];
                app.dirRGB(1:realsenseIndexStart-1,:)=[];
                app.dirDeepth(1:realsenseIndexStart-1,:)=[];
                app.imuData(1:imuIndexStart-1,:)=[];
                %激光雷达
                app.LidarData.AngleHorizontal(1:lidarIndexStart-1,:)=[];
                AngleVertical(1:lidarIndexStart-1,:)=[];
                app.LidarData.dist(1:lidarIndexStart-1,:)=[];
                app.LidarData.reflectivity(1:lidarIndexStart-1,:)=[];
                
                
                app.LidarData.z=app.LidarData.dist.*sind(AngleVertical);
                app.LidarData.y=app.LidarData.dist.*cosd(AngleVertical).*cosd(app.LidarData.AngleHorizontal);
                app.LidarData.x=app.LidarData.dist.*cosd(AngleVertical).*sind(app.LidarData.AngleHorizontal);
                
                %分帧
                [~,ia,~] = unique(lidarTimeNum);
%                 temp=app.LidarData.AngleHorizontal(ia)-app.LidarData.AngleHorizontal(1);
%                 app.LidarData.sequence=ia(diff(sign(temp))== 2);
                [pks,locs] = findpeaks(app.LidarData.AngleHorizontal(ia));
                app.LidarData.sequence=ia(locs(pks>350))+1;
                lidarTimeNum=[0;lidarTimeNum(app.LidarData.sequence)];
                
                
                [~,ia,~] = unique([app.imuData.lat,app.imuData.lng],'rows','stable');
                app.TimeNum.gps=app.imuData.timenum(ia);
                app.gpsData.lat=app.imuData.lat(ia);
                app.gpsData.lng=app.imuData.lng(ia);
                %lat,lng转换
                lla=[app.gpsData.lat,app.gpsData.lng,zeros(size(app.gpsData.lat))];p = lla2ecef(lla, 'WGS84');
                [app.gpsData.xEast,app.gpsData.yNorth,~] = ecef2enu(p(:,1),p(:,2),p(:,3),app.gpsData.lat(1),app.gpsData.lng(1),0,wgs84Ellipsoid);
                
                %可以删掉app.imuData.timenum以及.lat,.lng
                app.TimeNum.miliRadar=miliRadarTimeNum;
                app.TimeNum.realsense=realsenseTimeNum;
                app.TimeNum.imu=app.imuData.timenum;
                app.TimeNum.lidar=lidarTimeNum;
                
                %设置GPS图坐标轴范围
                app.GPSRoute.XLim = [min(app.gpsData.xEast) max(app.gpsData.xEast)];
                app.GPSRoute.YLim = [min(app.gpsData.yNorth) max(app.gpsData.yNorth)];
                
                app.indexLidarCSV=app.indexLidarCSV+1;
            end
        end

        % Button pushed function: begin
        function beginButtonPushed(app, event)
            %%各数据大致周期,单位s
            %periodImu=0.032;
            %periodRealSense=0.064;
            %periodMiliRadar=0.2;
            %periodLidar=0.1;
            %每个csv含50个周期的数据，约10秒
            
            aheadSec=118;%跳过的秒数%118开始绕%69提起来
            [~,index_miliRadar]=min(abs(app.TimeNum.miliRadar-aheadSec));
            [~,index_imu_start]=min(abs(app.TimeNum.imu-aheadSec));
            [~,index_realsense]=min(abs(app.TimeNum.realsense-aheadSec));
            [~,index_gps]=min(abs(app.TimeNum.gps-aheadSec));
            if aheadSec<=app.TimeNum.lidar(end)
                [~,index_lidar]=min(abs(app.TimeNum.lidar-aheadSec));
            else
                %小于33包比较稳健,也就是超前330内
                app.indexLidarCSV=floor((aheadSec+app.timeLidarOpen.actualTime)/10)+1;
                filename = [app.lidarDirCSV(app.indexLidarCSV).folder,'/',app.lidarDirCSV(app.indexLidarCSV).name];
                [app.LidarData.AngleHorizontal,AngleVertical,app.LidarData.dist,lidarTimeNum,app.LidarData.reflectivity]=readLidarCSV(app,filename);
                lidarTimeNum=lidarTimeNum-app.timeLidarOpen.lidarTime;
                [~,ia,~] = unique(lidarTimeNum);
                %分帧
                [pks,locs] = findpeaks(app.LidarData.AngleHorizontal(ia));
                app.LidarData.sequence=ia(locs(pks>350))+1;
                lidarTimeNum=[lidarTimeNum(1);lidarTimeNum(app.LidarData.sequence)];
                app.LidarData.z=app.LidarData.dist.*sind(AngleVertical);
                app.LidarData.y=app.LidarData.dist.*cosd(AngleVertical).*cosd(app.LidarData.AngleHorizontal);
                app.LidarData.x=app.LidarData.dist.*cosd(AngleVertical).*sind(app.LidarData.AngleHorizontal);
                app.TimeNum.lidar=lidarTimeNum;
                [~,index_lidar]=min(abs(app.TimeNum.lidar-aheadSec));              
                app.indexLidarCSV=app.indexLidarCSV+1;
            end
            
            for index_imu=index_imu_start:length(app.TimeNum.imu)
                %加载Lidar数据
                if index_lidar==length(app.LidarData.sequence)+1 && app.TimeNum.lidar(index_lidar)<=app.TimeNum.imu(index_imu)
                    printText2(app,'加载激光雷达数据...');    
                    filename = [app.lidarDirCSV(app.indexLidarCSV).folder,'/',app.lidarDirCSV(app.indexLidarCSV).name];
                    [app.LidarData.AngleHorizontal,AngleVertical,app.LidarData.dist,lidarTimeNum,app.LidarData.reflectivity]=readLidarCSV(app,filename);
                    lidarTimeNum=lidarTimeNum-app.timeLidarOpen.lidarTime;
                    [~,ia,~] = unique(lidarTimeNum);                    
                    %分帧
                    [pks,locs] = findpeaks(app.LidarData.AngleHorizontal(ia));
                    app.LidarData.sequence=ia(locs(pks>350))+1;
                    lidarTimeNum=[lidarTimeNum(1);lidarTimeNum(app.LidarData.sequence)];
                    app.LidarData.z=app.LidarData.dist.*sind(AngleVertical);
                    app.LidarData.y=app.LidarData.dist.*cosd(AngleVertical).*cosd(app.LidarData.AngleHorizontal);
                    app.LidarData.x=app.LidarData.dist.*cosd(AngleVertical).*sind(app.LidarData.AngleHorizontal);
                    index_lidar=1;
                    app.TimeNum.lidar=lidarTimeNum;
                    app.indexLidarCSV=app.indexLidarCSV+1;
                end
                
                
                str=['*imu:',num2str(app.TimeNum.imu(index_imu)),...
                    ' Real:',num2str(app.TimeNum.realsense(index_realsense)),...
                    ' Mili:',num2str(app.TimeNum.miliRadar(index_miliRadar)),...
                    ' Lidar:',num2str(app.TimeNum.lidar(index_lidar))];
                printText2(app,str);    
                %disp(str)
                if app.StopFlag == true
                    app.StopFlag = false;
                    break
                end
                %Yaw输出
                %app.YawGauge.Value=app.imuData.yaw(index_imu);
                plotTansformCuber(app,app.imuData.yaw(index_imu),app.imuData.pitch(index_imu),app.imuData.roll(index_imu))
                %rotateCube(app,app.imuData.yaw(index_imu),app.imuData.pitch(index_imu),app.imuData.roll(index_imu))
                %GPS轨迹
                if app.TimeNum.gps(index_gps)<=app.TimeNum.imu(index_imu)
                    plot(app.GPSRoute,app.gpsData.xEast(index_gps),app.gpsData.yNorth(index_gps),'.b');
                    pause(1.e-5)
                    index_gps=index_gps+1;
                end
                %RealSense输出
                if app.TimeNum.realsense(index_realsense)<=app.TimeNum.imu(index_imu)
                    plotRealSense(app,index_realsense);
                    index_realsense=index_realsense+1;
                end
                %毫米波输出
                if app.TimeNum.miliRadar(index_miliRadar)<=app.TimeNum.imu(index_imu)
                    plotMiliRadar(app,index_miliRadar);
                    index_miliRadar=index_miliRadar+1;
                end
                %激光雷达输出
                if app.TimeNum.lidar(index_lidar)<=app.TimeNum.imu(index_imu)
                    plotLidar(app,index_lidar);
                    index_lidar=index_lidar+1;
                end
                pause(1.e-5)

            end
            
        end

        % Button pushed function: Pause
        function PauseButtonPushed(app, event)
            if app.PauseFlag==false
                app.PauseFlag=true;
                app.Pause.Text = '继续';
                uiwait(app.UIFigure)
            else
                app.PauseFlag=false;
                app.Pause.Text = '暂停';
                uiresume(app.UIFigure)
            end
        end

        % Button pushed function: Stop
        function StopButtonPushed(app, event)
            if app.StopFlag==false
                cla(app.MillimeterWaveRadar1);
                cla(app.MillimeterWaveRadar2);
                cla(app.MillimeterWaveRadar3);
                cla(app.MillimeterWaveRadar4);
                cla(app.Lidar);
                cla(app.GPSRoute);
                cla(app.Euler);
                app.Euler.Color = [0.00,0.00,0.00];                
                allBlack=uint8(zeros(720,1280,3));%强制黑色
                imshow(allBlack,'Parent',app.RGBPic);
                imshow(allBlack,'Parent',app.DeepPic);
                %TextOut
                app.TextOut.Value = '';
                %app.cellText = {};
                %app.textIndex =1;
                
                %app.YawGauge.Value=0;
                app.StopFlag=true;
                app.Pause.Text = '暂停';
                app.PauseFlag=false;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Lidar = uiaxes(app.UIFigure);
            title(app.Lidar, '激光雷达')
            app.MillimeterWaveRadar1 = uiaxes(app.UIFigure);
            title(app.MillimeterWaveRadar1, '毫米波雷达 右')
            xlabel(app.MillimeterWaveRadar1, 'x')
            ylabel(app.MillimeterWaveRadar1, 'y')
            app.MillimeterWaveRadar2 = uiaxes(app.UIFigure);
            title(app.MillimeterWaveRadar2, '毫米波雷达 后')
            xlabel(app.MillimeterWaveRadar2, 'x')
            ylabel(app.MillimeterWaveRadar2, 'y')
            app.MillimeterWaveRadar3 = uiaxes(app.UIFigure);
            title(app.MillimeterWaveRadar3, '毫米波雷达 前')
            xlabel(app.MillimeterWaveRadar3, 'x')
            ylabel(app.MillimeterWaveRadar3, 'y')
            app.MillimeterWaveRadar4 = uiaxes(app.UIFigure);
            title(app.MillimeterWaveRadar4, '毫米波雷达 左')
            xlabel(app.MillimeterWaveRadar4, 'y')
            ylabel(app.MillimeterWaveRadar4, 'x')
            app.RGBPic = uiaxes(app.UIFigure);
            title(app.RGBPic, 'RGB')
            app.RGBPic.XTick = [];
            app.RGBPic.YTick = [];
            app.RGBPic.ZTick = [];
            app.DeepPic = uiaxes(app.UIFigure);
            title(app.DeepPic, 'Deep')
            app.DeepPic.XTick = [];
            app.DeepPic.YTick = [];
            app.DeepPic.ZTick = [];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '状态输出';
            app.TextOut = uitextarea(app.UIFigure);
            app.openDir = uibutton(app.UIFigure, 'push');
            app.openDir.ButtonPushedFcn = createCallbackFcn(app, @openDirButtonPushed, true);
            app.openDir.Text = '选择文件夹';
            app.begin = uibutton(app.UIFigure, 'push');
            app.begin.ButtonPushedFcn = createCallbackFcn(app, @beginButtonPushed, true);
            app.begin.Text = '开始';
            app.Pause = uibutton(app.UIFigure, 'push');
            app.Pause.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.Pause.Text = '暂停';
            app.Stop = uibutton(app.UIFigure, 'push');
            app.Stop.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.Stop.Text = '停止';
            app.GPSRoute = uiaxes(app.UIFigure);
            title(app.GPSRoute, 'GPS轨迹')
            xlabel(app.GPSRoute, 'East /m')
            ylabel(app.GPSRoute, 'North /m')
            app.Euler = uiaxes(app.UIFigure);
            title(app.Euler, '欧拉角显示')
            app.Euler.XTick = [];
            app.Euler.YTick = [];
        end
    end

    methods (Access = public)

        % Construct app
        function app = integrated_black_linux

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end